classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        edit1EditField       matlab.ui.control.EditField
        edit1EditFieldLabel  matlab.ui.control.Label
        Slider               matlab.ui.control.Slider
        SliderLabel          matlab.ui.control.Label
        GetImageButton       matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetImageButton
        function GetImageButtonPushed(app, event)
            global Image valor_min valor_max
            path=uigetdir;
path=strcat(path,'\');
filelistdcm=dir(path);
names={filelistdcm.name};
names=names(~strncmp(names,'.',1));
[x,n_img]=size(names);
path_image=strcat(path,char(names(1)));
I_base=dicomread(path_image);
[n_rows,n_cols]=size(I_base);
Image=zeros(n_rows,n_cols,n_img);
global_max=realmin;
global_min=realmax;
for i=1:1:n_img
    h=char(names(i));
    h=strcat(path,'\',h);
    DirInfo=dicominfo(h);
    n=DirInfo.InstanceNumber;
    current_data=dicomread(h);
    m=DirInfo.RescaleSlope;
    b=DirInfo.RescaleIntercept;
    current_data_fixed=m.*current_data+b;
    current_max=max(max(current_data_fixed));
    current_min=min(min(current_data_fixed));
    Image(:,:,n)=current_data_fixed;
    if(current_min<global_min)
        global_min=current_min;
    end
    if(current_max>global_max)
        global_max=current_max;
    end
end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            global Image valor_min valor_max
            value = app.Slider.Value;
            Axial=permute(Image(:,:,value+1),[1,2,3]);
            axes(app.UIAxes);
            imshow(Axial(:,:,1),[valor_min,valor_max])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GetImageButton = uibutton(app.UIFigure, 'push');
            app.GetImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageButtonPushed, true);
            app.GetImageButton.Text = {'Get Image'; ''};
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.edit1EditFieldLabel = uilabel(app.UIFigure);
            app.edit1EditFieldLabel.Text = 'edit1';
            app.edit1EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end